classdef ImageStackPlayer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
        Slider    matlab.ui.control.Slider
        XXXLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        imageData % 3D image data
        dataMode % data mode NHW, HWN
        H,
        W,
        N,
    end
    
    methods (Access = private)
        function drawImage(app,N)
            if strcmp(app.dataMode,'NHW')
                imagesc(app.UIAxes,squeeze(app.imageData(N,:,:))); 
            else
                imagesc(app.UIAxes,squeeze(app.imageData(:,:,N))); 
            end
            colormap(app.UIAxes,'gray');
            app.UIAxes.XLim = [1,app.W];
            app.UIAxes.YLim = [1,app.H];
            app.XXXLabel.Text = num2str(N);
        end
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, imageData, dataMode)
            app.imageData = imageData;
            app.dataMode = dataMode;
            if strcmp(app.dataMode,'NHW')
                app.N = size(imageData,1);
                app.H = size(imageData,2);
                app.W = size(imageData,3);
            elseif strcmp(app.dataMode,'HWN')
                app.H = size(imageData,1);
                app.W = size(imageData,2);
                app.N = size(imageData,3);
            else
                error('ImageStackPlayer: invalid data mode, expected HWN or NHW, given %s',app.dataMode);
            end
            app.Slider.Limits = [1,app.N];
            app.drawImage(1);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.drawImage(max([min([round(changingValue),app.N]),1]));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.XXXLabel = uilabel(app.UIFigure);
            app.XXXLabel.Text = 'XXX';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageStackPlayer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end